classdef myscara_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        axes1                  matlab.ui.control.UIAxes
        uipanel6               matlab.ui.container.Panel
        checkbox_c0            matlab.ui.control.CheckBox
        checkbox_c1            matlab.ui.control.CheckBox
        checkbox_c2            matlab.ui.control.CheckBox
        checkbox_c3            matlab.ui.control.CheckBox
        checkbox_c4            matlab.ui.control.CheckBox
        uipanel5               matlab.ui.container.Panel
        edit_a1                matlab.ui.control.EditField
        text_a1                matlab.ui.control.Label
        edit_a2                matlab.ui.control.EditField
        text_a2                matlab.ui.control.Label
        text_d4                matlab.ui.control.Label
        edit_d4                matlab.ui.control.EditField
        uipanel4               matlab.ui.container.Panel
        slider_d3              matlab.ui.control.Slider
        slider_theta1          matlab.ui.control.Slider
        slider_theta2          matlab.ui.control.Slider
        text_d3                matlab.ui.control.Label
        text_theta1            matlab.ui.control.Label
        text_theta2            matlab.ui.control.Label
        edit_d3                matlab.ui.control.EditField
        edit_theta1            matlab.ui.control.EditField
        edit_theta2            matlab.ui.control.EditField
        slider_theta4          matlab.ui.control.Slider
        edit_theta4            matlab.ui.control.EditField
        text_theta4            matlab.ui.control.Label
        btn_Start              matlab.ui.control.Button
        text14                 matlab.ui.control.Label
        text31                 matlab.ui.control.Label
        uipanel3               matlab.ui.container.Panel
        uipanel2               matlab.ui.container.Panel
        text17                 matlab.ui.control.Label
        text18                 matlab.ui.control.Label
        text21                 matlab.ui.control.Label
        text22                 matlab.ui.control.Label
        text23                 matlab.ui.control.Label
        text24                 matlab.ui.control.Label
        text25                 matlab.ui.control.Label
        edit_p1_x              matlab.ui.control.EditField
        edit_p1_y              matlab.ui.control.EditField
        edit_p1_z              matlab.ui.control.EditField
        edit_p1_roll           matlab.ui.control.EditField
        edit_p1_pitch          matlab.ui.control.EditField
        edit_p1_yaw            matlab.ui.control.EditField
        edit_p2_x              matlab.ui.control.EditField
        edit_p2_y              matlab.ui.control.EditField
        edit_p2_z              matlab.ui.control.EditField
        edit_p2_roll           matlab.ui.control.EditField
        edit_p2_pitch          matlab.ui.control.EditField
        edit_p2_yaw            matlab.ui.control.EditField
        edit_p3_x              matlab.ui.control.EditField
        edit_p3_y              matlab.ui.control.EditField
        edit_p3_z              matlab.ui.control.EditField
        edit_p3_roll           matlab.ui.control.EditField
        edit_p3_pitch          matlab.ui.control.EditField
        edit_p3_yaw            matlab.ui.control.EditField
        edit_p4_x              matlab.ui.control.EditField
        edit_p4_y              matlab.ui.control.EditField
        edit_p4_z              matlab.ui.control.EditField
        edit_p4_roll           matlab.ui.control.EditField
        edit_p4_pitch          matlab.ui.control.EditField
        edit_p4_yaw            matlab.ui.control.EditField
        text26                 matlab.ui.control.Label
        text27                 matlab.ui.control.Label
        text28                 matlab.ui.control.Label
        text29                 matlab.ui.control.Label
        pushbutton_InvDynamic  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function myscara_gui_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to myscara_gui (see VARARGIN)
%             
%             % Choose default command line output for myscara_gui
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: btn_Start
        function btn_Start_Callback(app, event)
            % hObject    handle to btn_Start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            plot_robot(handles)
        end

        % Value changed function: checkbox_c0
        function checkbox_c0_Callback(app, event)
            % hObject    handle to checkbox_c0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            plot_robot(handles)
        end

        % Value changed function: checkbox_c1
        function checkbox_c1_Callback(app, event)
            % hObject    handle to checkbox_c1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            plot_robot(handles)
        end

        % Value changed function: checkbox_c2
        function checkbox_c2_Callback(app, event)
            % hObject    handle to checkbox_c2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            plot_robot(handles)
        end

        % Value changed function: checkbox_c3
        function checkbox_c3_Callback(app, event)
            % hObject    handle to checkbox_c3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            plot_robot(handles)
        end

        % Value changed function: checkbox_c4
        function checkbox_c4_Callback(app, event)
            % hObject    handle to checkbox_c4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            plot_robot(handles)
        end

        % Button pushed function: pushbutton_InvDynamic
        function pushbutton_InvDynamic_Callback(app, event)
            % hObject    handle to pushbutton_InvDynamic (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Cal_inverse_dynamic(handles)
        end

        % Value changed function: slider_d3
        function slider_d3_Callback(app, event)
            % hObject    handle to slider_d3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                set(app.slider_d3, 'Max',0.33);
                set(app.slider_d3, 'Min', 0);
                set(app.slider_d3, 'SliderStep' , [1, 1] / 20 );
                sliderValue_d3 = get(app.slider_d3,'Value');
                set(app.edit_d3,'String',sliderValue_d3)
                plot_robot(handles)
        end

        % Value changed function: slider_theta1
        function slider_theta1_Callback(app, event)
            % hObject    handle to slider_theta1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                set(app.slider_theta1, 'Max', 180);
                set(app.slider_theta1, 'Min', -180);
                set(app.slider_theta1, 'SliderStep' , [1, 1] / 20 );
                sliderValue_theta1 = get(app.slider_theta1,'Value');
                set(app.edit_theta1,'String',sliderValue_theta1)
                plot_robot(handles)
        end

        % Value changed function: slider_theta2
        function slider_theta2_Callback(app, event)
            % hObject    handle to slider_theta2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                set(app.slider_theta2, 'Max', 180);
                set(app.slider_theta2, 'Min', -180);
                set(app.slider_theta2, 'SliderStep' , [1, 1] / 20 );
                sliderValue_theta2 = get(app.slider_theta2,'Value');
                set(app.edit_theta2,'String',sliderValue_theta2)
                plot_robot(handles)
        end

        % Value changed function: slider_theta4
        function slider_theta4_Callback(app, event)
            % hObject    handle to slider_theta4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                set(app.slider_theta4, 'Max', 180);
                set(app.slider_theta4, 'Min', -180);
                set(app.slider_theta4, 'SliderStep' , [1, 1] / 20 );
                sliderValue_theta4 = get(app.slider_theta4,'Value');
                set(app.edit_theta4,'String',sliderValue_theta4)
                plot_robot(handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'myscara_gui';
            app.axes1 = uiaxes(app.figure1);
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Coordinate options';
            app.checkbox_c0 = uicheckbox(app.uipanel6);
            app.checkbox_c0.ValueChangedFcn = createCallbackFcn(app, @checkbox_c0_Callback, true);
            app.checkbox_c0.Text = 'Coordinate 0 visible';
            app.checkbox_c1 = uicheckbox(app.uipanel6);
            app.checkbox_c1.ValueChangedFcn = createCallbackFcn(app, @checkbox_c1_Callback, true);
            app.checkbox_c1.Text = 'Coordinate 1 visible';
            app.checkbox_c2 = uicheckbox(app.uipanel6);
            app.checkbox_c2.ValueChangedFcn = createCallbackFcn(app, @checkbox_c2_Callback, true);
            app.checkbox_c2.Text = 'Coordinate 2 visible';
            app.checkbox_c3 = uicheckbox(app.uipanel6);
            app.checkbox_c3.ValueChangedFcn = createCallbackFcn(app, @checkbox_c3_Callback, true);
            app.checkbox_c3.Text = 'Coordinate 3 visible';
            app.checkbox_c4 = uicheckbox(app.uipanel6);
            app.checkbox_c4.ValueChangedFcn = createCallbackFcn(app, @checkbox_c4_Callback, true);
            app.checkbox_c4.Text = 'Coordinate 4 visible';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'DH_Constants';
            app.edit_a1 = uieditfield(app.uipanel5, 'text');
            app.edit_a1.Value = '0.4';
            app.text_a1 = uilabel(app.uipanel5);
            app.text_a1.Text = 'a1';
            app.edit_a2 = uieditfield(app.uipanel5, 'text');
            app.edit_a2.Value = '0.25';
            app.text_a2 = uilabel(app.uipanel5);
            app.text_a2.Text = 'a2';
            app.text_d4 = uilabel(app.uipanel5);
            app.text_d4.Text = 'd4';
            app.edit_d4 = uieditfield(app.uipanel5, 'text');
            app.edit_d4.Value = '0.05';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'DH_Variables';
            app.slider_d3 = uislider(app.uipanel4);
            app.slider_d3.Limits = [0 1];
            app.slider_d3.MajorTicks = [];
            app.slider_d3.ValueChangedFcn = createCallbackFcn(app, @slider_d3_Callback, true);
            app.slider_d3.MinorTicks = [];
            app.slider_theta1 = uislider(app.uipanel4);
            app.slider_theta1.Limits = [0 1];
            app.slider_theta1.MajorTicks = [];
            app.slider_theta1.ValueChangedFcn = createCallbackFcn(app, @slider_theta1_Callback, true);
            app.slider_theta1.MinorTicks = [];
            app.slider_theta2 = uislider(app.uipanel4);
            app.slider_theta2.Limits = [0 1];
            app.slider_theta2.MajorTicks = [];
            app.slider_theta2.ValueChangedFcn = createCallbackFcn(app, @slider_theta2_Callback, true);
            app.slider_theta2.MinorTicks = [];
            app.text_d3 = uilabel(app.uipanel4);
            app.text_d3.Text = 'd3';
            app.text_theta1 = uilabel(app.uipanel4);
            app.text_theta1.Text = 'theta1';
            app.text_theta2 = uilabel(app.uipanel4);
            app.text_theta2.Text = 'theta2';
            app.edit_d3 = uieditfield(app.uipanel4, 'text');
            app.edit_d3.Value = '0';
            app.edit_theta1 = uieditfield(app.uipanel4, 'text');
            app.edit_theta1.Value = '0';
            app.edit_theta2 = uieditfield(app.uipanel4, 'text');
            app.edit_theta2.Value = '0';
            app.slider_theta4 = uislider(app.uipanel4);
            app.slider_theta4.Limits = [0 1];
            app.slider_theta4.MajorTicks = [];
            app.slider_theta4.ValueChangedFcn = createCallbackFcn(app, @slider_theta4_Callback, true);
            app.slider_theta4.MinorTicks = [];
            app.edit_theta4 = uieditfield(app.uipanel4, 'text');
            app.edit_theta4.Value = '0';
            app.text_theta4 = uilabel(app.uipanel4);
            app.text_theta4.Text = 'theta4';
            app.btn_Start = uibutton(app.figure1, 'push');
            app.btn_Start.ButtonPushedFcn = createCallbackFcn(app, @btn_Start_Callback, true);
            app.btn_Start.Text = 'Start';
            app.text14 = uilabel(app.figure1);
            app.text14.Text = 'SCARA ROBOT SIMULATION';
            app.text31 = uilabel(app.figure1);
            app.text31.Text = 'Author: Ngo An Thuyen - HCMUT  1713397';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'POSE';
            app.uipanel2 = uipanel(app.uipanel3);
            app.text17 = uilabel(app.uipanel3);
            app.text17.Text = 'x [m]';
            app.text18 = uilabel(app.uipanel3);
            app.text18.Text = 'y [m]';
            app.text21 = uilabel(app.uipanel3);
            app.text21.Text = 'z [m]';
            app.text22 = uilabel(app.uipanel3);
            app.text22.Text = 'Roll [Deg]';
            app.text23 = uilabel(app.uipanel3);
            app.text23.Text = 'Pitch [Deg]';
            app.text24 = uilabel(app.uipanel3);
            app.text24.Text = 'Yaw [Deg]';
            app.text25 = uilabel(app.uipanel3);
            app.text25.Text = 'Joint';
            app.edit_p1_x = uieditfield(app.uipanel3, 'text');
            app.edit_p1_y = uieditfield(app.uipanel3, 'text');
            app.edit_p1_z = uieditfield(app.uipanel3, 'text');
            app.edit_p1_roll = uieditfield(app.uipanel3, 'text');
            app.edit_p1_pitch = uieditfield(app.uipanel3, 'text');
            app.edit_p1_yaw = uieditfield(app.uipanel3, 'text');
            app.edit_p2_x = uieditfield(app.uipanel3, 'text');
            app.edit_p2_y = uieditfield(app.uipanel3, 'text');
            app.edit_p2_z = uieditfield(app.uipanel3, 'text');
            app.edit_p2_roll = uieditfield(app.uipanel3, 'text');
            app.edit_p2_pitch = uieditfield(app.uipanel3, 'text');
            app.edit_p2_yaw = uieditfield(app.uipanel3, 'text');
            app.edit_p3_x = uieditfield(app.uipanel3, 'text');
            app.edit_p3_y = uieditfield(app.uipanel3, 'text');
            app.edit_p3_z = uieditfield(app.uipanel3, 'text');
            app.edit_p3_roll = uieditfield(app.uipanel3, 'text');
            app.edit_p3_pitch = uieditfield(app.uipanel3, 'text');
            app.edit_p3_yaw = uieditfield(app.uipanel3, 'text');
            app.edit_p4_x = uieditfield(app.uipanel3, 'text');
            app.edit_p4_y = uieditfield(app.uipanel3, 'text');
            app.edit_p4_z = uieditfield(app.uipanel3, 'text');
            app.edit_p4_roll = uieditfield(app.uipanel3, 'text');
            app.edit_p4_pitch = uieditfield(app.uipanel3, 'text');
            app.edit_p4_yaw = uieditfield(app.uipanel3, 'text');
            app.text26 = uilabel(app.uipanel3);
            app.text26.Text = '1';
            app.text27 = uilabel(app.uipanel3);
            app.text27.Text = '2';
            app.text28 = uilabel(app.uipanel3);
            app.text28.Text = '3';
            app.text29 = uilabel(app.uipanel3);
            app.text29.Text = '4';
            app.pushbutton_InvDynamic = uibutton(app.figure1, 'push');
            app.pushbutton_InvDynamic.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_InvDynamic_Callback, true);
            app.pushbutton_InvDynamic.Text = 'Inverse Dynamic';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myscara_gui_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @myscara_gui_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end